classdef BA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        NameLeitungenEditFieldLabel  matlab.ui.control.Label
        NameLeitungenEditField       matlab.ui.control.EditField
        NamendereinzulesendenOpenSourceDatenLabel  matlab.ui.control.Label
        NameKnotenEditFieldLabel     matlab.ui.control.Label
        NameKnotenEditField          matlab.ui.control.EditField
        ImportierenButton            matlab.ui.control.Button
        LeitungsbelegeSwitchLabel    matlab.ui.control.Label
        LeitungsbelegeSwitch         matlab.ui.control.Switch
        NorddeutschlandButton        matlab.ui.control.Button
        KnotenauerhalbNorddeutshclandsentfernenLabel  matlab.ui.control.Label
        ReduktionsschritteLabel      matlab.ui.control.Label
        AnzahlKnotenEditFieldLabel   matlab.ui.control.Label
        AnzahlKnotenEditField        matlab.ui.control.NumericEditField
        LandkreiseButton             matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        ExportButton                 matlab.ui.control.Button
    end

    
    properties (Access = public)
        leitungsfilename % name der Open-Spurce Leitungsdaten
        knotenfilename   % name der Open-Source Knotendaten
        Leitungen        
        Knoten
        Landkreis
        Knotenspannung
        Y
    end
    

    methods (Access = private)

        % Button pushed function: ImportierenButton
        function ImportierenButtonPushed(app, event)
            Leitungenfilename=app.NameLeitungenEditField.Value;
            Knotenfilename=app.NameKnotenEditField.Value;
            filename=['opensourcedaten\',Leitungenfilename,'.xlsx'];
            [app.Leitungen]=xlsread(filename);
            
            filename=['opensourcedaten\',Knotenfilename,'.xlsx'];
            [num,~,raw] = xlsread(filename);
            app.Landkreis=raw(1:end,6);
            app.Landkreis=string(app.Landkreis);
            app.Knotenspannung=raw(1:end,3);
            app.Knotenspannung=string(app.Knotenspannung);
            app.Knoten = [num(:,1),num(1:end,2),num(:,4),num(:,5),num(:,7)];
            if app.LeitungsbelegeSwitch.Value=='1'
                app.Leitungen=app.Leitungen(:,1:8);
            elseif app.LeitungsbelegeSwitch.Value=='2'
                app.Leitungen=app.Leitungen(:,1:5);
            end
            
            [AnzL,~]=size(app.Leitungen);   %Anzahl der Leitungen bestimmen
            if app.LeitungsbelegeSwitch.Value=='2'
                R=zeros(AnzL,1);
                X=zeros(AnzL,1);
                B=zeros(AnzL,1);
                %Bezugsimpedanz Zb=128 Ohm
                Rb380=1.953e-04; Xb380=1.953e-3; Bb380=5.509e-04;  %normierte Leitungsbelege für 380000V Leitungen
                Rb220=6.25e-04;Xb220=2.5e-3;Bb220=4.624e-04;       %normierte Leitungsbelege für 220000V Leitungen
                %Die Berechnungen sind in Leitungsbelege zu finden
            else
            end
            for i=1:AnzL                      %Über die Leitungslänge den absoluten Werte bestimmen (Länge*Belag)
                if app.Leitungen(i,4)==380000
                    R(i)=app.Leitungen(i,3)*Rb380;     %Wiederstand
                    X(i)=app.Leitungen(i,3)*Xb380;     %Längsreaktanz
                    B(i)=app.Leitungen(i,3)*Bb380;     %Quersuszeptanz
                elseif app.Leitungen(i,4)==220000
                    R(i)=app.Leitungen(i,3)*Rb220;     %Wiederstand
                    X(i)=app.Leitungen(i,3)*Xb220;     %Längsreaktanz
                    B(i)=app.Leitungen(i,3)*Bb220;     %Quersuszeptanz
                else
                    %disp('Leitung ist nicht Teil des Übertragungernetzes') %Leitungen ohne Spannungsangaben werden nicht als Teil des Verteilernetzes angesehen.
                    R(i)=0;                                                 %Dementsprechend werden die Leitungsbelege auf 0 gesetzt.
                    X(i)=0;
                    B(i)=0;
                end
            end
            %% Admittanzmatrix erstellen
            AnzK=max(app.Knoten(:,1));       %Anzahl der Knoten
            app.Y = zeros(AnzK,AnzK);        %app.Y Admittanzmatrix
            for lauf=1:AnzL
                ak  = app.Leitungen(lauf,1);
                ek  = app.Leitungen(lauf,2);
                if X(lauf)
                    yij = inv(R(lauf)+1i*X(lauf));
                    yii = 1i*0.5*B(lauf);
                end
                app.Y(ak,ak) = app.Y(ak,ak)+yii+yij;
                app.Y(ek,ek) = app.Y(ek,ek)+yii+yij;
                app.Y(ak,ek) = app.Y(ak,ek)-yij;
                app.Y(ek,ak) = app.Y(ek,ak)-yij;
            end
            [AnzK,~]=size(app.Knoten);
            app.AnzahlKnotenEditField.Value=AnzK;
        end

        % Button pushed function: NorddeutschlandButton
        function NorddeutschlandButtonPushed(app, event)
            %%Entfernt alle Knoten auserhalb Norddeutschlands
            AnzK=max(app.Knoten(:,1));
            for lauf=1:AnzK                    %geht alle Knotennummern durch
                Indize=app.Knoten(:,1)==lauf;  %bestimmt den Indize in Knoten zur Knotennummer lauf.
                if app.Knoten(Indize,5)==0     %ist der Knoten zu lauf auserhalb Norddeutschlands wird er entfernt.
                    app=Knotenentfernen(lauf,app);
                end
            end
            [AnzK,~]=size(app.Knoten);
            app.AnzahlKnotenEditField.Value=AnzK;
        end

        % Button pushed function: LandkreiseButton
        function LandkreiseButtonPushed(app, event)
            %Sollten in einem Landkreis mehrere Knoten liegen, werden diese zu einem
            %Superknoten zusammengefasst.
            [AnzK,~]=size(app.Knoten);
            [Landkreisesort,sortind]=sort(app.Landkreis);  %nach namen sortierter Landkreisvektor
            sortnr=app.Knoten(sortind,1);                  %dazugehörige Knotennummern
            lauf=1;
            while AnzK-1>lauf
                [AnzK,~]=size(Landkreisesort);
                if all(Landkreisesort(lauf)==Landkreisesort(lauf+1))&&Landkreisesort(lauf)~='0'
                    app=Superknoten(sortnr(lauf),sortnr(lauf+1),app);
                    Landkreisesort(lauf+1)=[];
                    sortnr(lauf+1)=[];
                else
                    lauf=lauf+1;
                end
            end
            app.AnzahlKnotenEditField.Value=AnzK;
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            %saves the Data in app in Outputdata\Data
            filename='Outputdata\Data';
            save(filename,'app','.m');
            clear filename
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.NameLeitungenEditFieldLabel = uilabel(app.UIFigure);
            app.NameLeitungenEditFieldLabel.Text = 'Name Leitungen';
            app.NameLeitungenEditField = uieditfield(app.UIFigure, 'text');
            app.NamendereinzulesendenOpenSourceDatenLabel = uilabel(app.UIFigure);
            app.NamendereinzulesendenOpenSourceDatenLabel.Text = 'Namen der einzulesenden Open-Source Daten';
            app.NameKnotenEditFieldLabel = uilabel(app.UIFigure);
            app.NameKnotenEditFieldLabel.Text = 'Name Knoten';
            app.NameKnotenEditField = uieditfield(app.UIFigure, 'text');
            app.ImportierenButton = uibutton(app.UIFigure, 'push');
            app.ImportierenButton.ButtonPushedFcn = createCallbackFcn(app, @ImportierenButtonPushed, true);
            app.ImportierenButton.Text = 'Importieren';
            app.LeitungsbelegeSwitchLabel = uilabel(app.UIFigure);
            app.LeitungsbelegeSwitchLabel.Text = 'Leitungsbelege';
            app.LeitungsbelegeSwitch = uiswitch(app.UIFigure, 'slider');
            app.LeitungsbelegeSwitch.Items = {'Vorhanden', 'nicht Vorhanden'};
            app.LeitungsbelegeSwitch.ItemsData = {'1', '2'};
            app.LeitungsbelegeSwitch.Value = '1';
            app.NorddeutschlandButton = uibutton(app.UIFigure, 'push');
            app.NorddeutschlandButton.ButtonPushedFcn = createCallbackFcn(app, @NorddeutschlandButtonPushed, true);
            app.NorddeutschlandButton.Text = 'Norddeutschland';
            app.KnotenauerhalbNorddeutshclandsentfernenLabel = uilabel(app.UIFigure);
            app.KnotenauerhalbNorddeutshclandsentfernenLabel.Text = 'Knoten außerhalb Norddeutshclands entfernen';
            app.ReduktionsschritteLabel = uilabel(app.UIFigure);
            app.ReduktionsschritteLabel.Text = 'Reduktionsschritte';
            app.AnzahlKnotenEditFieldLabel = uilabel(app.UIFigure);
            app.AnzahlKnotenEditFieldLabel.Text = 'Anzahl Knoten';
            app.AnzahlKnotenEditField = uieditfield(app.UIFigure, 'numeric');
            app.LandkreiseButton = uibutton(app.UIFigure, 'push');
            app.LandkreiseButton.ButtonPushedFcn = createCallbackFcn(app, @LandkreiseButtonPushed, true);
            app.LandkreiseButton.Text = 'Landkreise';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Bildet aus doppelten Knoten inerhalb eines Landkreises einen Superknoten';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
        end
    end

    methods (Access = public)

        % Construct app
        function app = BA

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end